classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MinSpinnerLabel             matlab.ui.control.Label
        CorticalMin                 matlab.ui.control.Spinner
        MaxSpinnerLabel             matlab.ui.control.Label
        CorticalMax                 matlab.ui.control.Spinner
        MinSpinner_3Label           matlab.ui.control.Label
        TrabecularMin               matlab.ui.control.Spinner
        MaxSpinner_2Label           matlab.ui.control.Label
        TrabecularMax               matlab.ui.control.Spinner
        SensibilidadSpinnerLabel    matlab.ui.control.Label
        SensibilidadSpinner         matlab.ui.control.Spinner
        CorticalLabel               matlab.ui.control.Label
        TrabecularLabel             matlab.ui.control.Label
        SimularButton               matlab.ui.control.Button
        CarpetaDICOMEditFieldLabel  matlab.ui.control.Label
        CarpetaDICOM                matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimularButton
        function ejecutar(app, event)
            close all;
            intensity = [0 20 40 120 220 1024]; %las distintas intensidades(valores,pixel,housfield) del conjunto
            alpha = [0 0 0.15 0.3 0.38 0.5]; %la transparencia que aplica (0 completamente transparente - 1 completamente opaco)
            color = ([0 0 0; 43 0 0; 103 37 20; 199 155 97; 216 213 201; 255 255 255])/ 255; %vector dematriz de 3x3 RGB para las intensidades
            queryPoints = linspace(min(intensity),max(intensity),256);
            alphamap = interp1(intensity,alpha,queryPoints)';
            colormap = interp1(intensity,color,queryPoints);
            
            ruta = app.CarpetaDICOM.Value;
            cmin = app.CorticalMin.Value;
            cmax = app.CorticalMax.Value;
            tmin = app.TrabecularMin.Value;
            tmax = app.TrabecularMax.Value;
            detalle = app.SensibilidadSpinner.Value;
            
            [volumenCortical, volumenTrabecular] = funcion(ruta,cmin,cmax,tmin,tmax,detalle);
            
            panel1 = uipanel(figure,'Title','Hueso cortical');
            volshow(volumenCortical,'Colormap',colormap,'Alphamap',alphamap,'Parent',panel1);
            
            panel2 = uipanel(figure,'Title','Hueso Trabecular');
            volshow(volumenTrabecular,'Colormap',colormap,'Alphamap',alphamap,'Parent',panel2);
            
            stlwrite('Cortical.stl',isosurface(~volumenCortical)); 
            stlwrite('trabecular.stl',isosurface(~volumenTrabecular));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MinSpinnerLabel = uilabel(app.UIFigure);
            app.MinSpinnerLabel.Text = 'Min';
            app.CorticalMin = uispinner(app.UIFigure);
            app.CorticalMin.Limits = [0 Inf];
            app.MaxSpinnerLabel = uilabel(app.UIFigure);
            app.MaxSpinnerLabel.Text = 'Max';
            app.CorticalMax = uispinner(app.UIFigure);
            app.CorticalMax.Limits = [0 Inf];
            app.MinSpinner_3Label = uilabel(app.UIFigure);
            app.MinSpinner_3Label.Text = 'Min';
            app.TrabecularMin = uispinner(app.UIFigure);
            app.TrabecularMin.Limits = [0 Inf];
            app.MaxSpinner_2Label = uilabel(app.UIFigure);
            app.MaxSpinner_2Label.Text = 'Max';
            app.TrabecularMax = uispinner(app.UIFigure);
            app.TrabecularMax.Limits = [0 Inf];
            app.SensibilidadSpinnerLabel = uilabel(app.UIFigure);
            app.SensibilidadSpinnerLabel.Text = 'Sensibilidad';
            app.SensibilidadSpinner = uispinner(app.UIFigure);
            app.SensibilidadSpinner.Limits = [0 Inf];
            app.CorticalLabel = uilabel(app.UIFigure);
            app.CorticalLabel.Text = 'Cortical';
            app.TrabecularLabel = uilabel(app.UIFigure);
            app.TrabecularLabel.Text = 'Trabecular';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @ejecutar, true);
            app.SimularButton.Text = 'Simular';
            app.CarpetaDICOMEditFieldLabel = uilabel(app.UIFigure);
            app.CarpetaDICOMEditFieldLabel.Text = 'Carpeta DICOM';
            app.CarpetaDICOM = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end